classdef comp2 < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        DoneLamp_4            matlab.ui.control.Lamp
        DoneLamp_4Label       matlab.ui.control.Label
        PLAYButton_2          matlab.ui.control.Button
        DoneLamp_3            matlab.ui.control.Lamp
        DoneLamp_3Label       matlab.ui.control.Label
        DoneLamp_2            matlab.ui.control.Lamp
        DoneLamp_2Label       matlab.ui.control.Label
        Fpass2EditField       matlab.ui.control.NumericEditField
        Fpass2EditFieldLabel  matlab.ui.control.Label
        DoneLamp              matlab.ui.control.Lamp
        DoneLampLabel         matlab.ui.control.Label
        PLAYButton            matlab.ui.control.Button
        RECButton             matlab.ui.control.Button
        Fpass1EditField       matlab.ui.control.NumericEditField
        Fpass1EditFieldLabel  matlab.ui.control.Label
        FsEditField           matlab.ui.control.NumericEditField
        FsEditFieldLabel      matlab.ui.control.Label
        APPLYButton           matlab.ui.control.Button
        UIAxes_4              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Fpass1
        Fpass2
        Fs
        Fs_new
        myAudio
        myAudio_filtered
        myAudio_resampled
        recObj
    end
    
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.DoneLamp.Enable='on';
            comp.DoneLamp_2.Enable='on';
            comp.DoneLamp_3.Enable='on';
            comp.DoneLamp_4.Enable='on';
            comp.Fpass1=1;
            comp.Fpass1EditField.Value=1;
            comp.Fpass2=3000;
            comp.Fpass2EditField.Value=3000;
            comp.Fs=16000;
            comp.FsEditField.Value=16000;
        end

        % Button pushed function: RECButton
        function RECButtonPushed(comp, event)
            comp.DoneLamp.Enable='off';
            [comp.myAudio,comp.recObj] = audiorecord();
            %重采样
            comp.Fs_new = 16000;
            comp.myAudio_resampled = resample(comp.myAudio, comp.Fs_new, comp.recObj.SampleRate);
            comp.DoneLamp.Enable = 'on';
            x = (0:length(comp.myAudio_resampled)-1)/comp.Fs_new;
            y = comp.myAudio_resampled;
            plot(comp.UIAxes,x,y);
            
            
            N = length(comp.myAudio_resampled);
            x = (0:N-1)*comp.Fs_new/N;
            y = abs(fft(comp.myAudio_resampled));
            plot(comp.UIAxes_2,x,y);
            comp.UIAxes_2.XLim=[0 comp.Fs_new/2];
            comp.UIAxes_2.YLim=[0 10];

        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(comp, event)
        %播放
        comp.DoneLamp_2.Enable="off";
        playerObj = audioplayer(comp.myAudio_resampled, comp.Fs_new);
        play(playerObj);
        pause(3);
        comp.DoneLamp_2.Enable="on";
        end

        % Value changed function: FsEditField
        function FsEditFieldValueChanged(comp, event)
            comp.Fs = comp.FsEditField.Value;
        end

        % Value changed function: Fpass1EditField
        function Fpass1EditFieldValueChanged(comp, event)
            comp.Fpass1 = comp.Fpass1EditField.Value;
        end

        % Value changed function: Fpass2EditField
        function Fpass2EditFieldValueChanged(comp, event)
            comp.Fpass2 = comp.Fpass2EditField.Value;
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(comp, event)
            comp.DoneLamp_3.Enable="off";
            Wpass = [comp.Fpass1, comp.Fpass2] / (comp.Fs/2);
            order = 100;
            h = fir1(order, Wpass, 'bandpass');
            % 对录得的语音信号进行滤波
            comp.myAudio_filtered = filter(h, 1, comp.myAudio_resampled);
            %查看处理后的语音信号的频率响应
            Y = abs(fft(comp.myAudio_filtered));
            N = length(comp.myAudio_resampled);
            f = (0:N-1)*comp.Fs_new/N;
            plot(comp.UIAxes_3,f,Y); %横坐标为频率f，纵坐标为幅度
            comp.UIAxes_3.XLim=[0 comp.Fs_new/2];
            comp.UIAxes_3.YLim=[0 10];

            t = (0:length(comp.myAudio_resampled)-1)/comp.Fs_new;
            plot(comp.UIAxes_4,t,comp.myAudio_filtered);
            comp.DoneLamp_3.Enable="on";
        end

        % Button pushed function: PLAYButton_2
        function PLAYButton_2Pushed(comp, event)
            comp.DoneLamp_4.Enable="off";
            playerObj = audioplayer(comp.myAudio_filtered, comp.Fs_new);
            play(playerObj);
            pause(3);
            comp.DoneLamp_4.Enable="on";
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % 使用此函数更新底层组件
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 1197 775];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create UIAxes
            comp.UIAxes = uiaxes(comp);
            xlabel(comp.UIAxes, 'Time (s)')
            ylabel(comp.UIAxes, 'Amplitude')
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            comp.UIAxes.XGrid = 'on';
            comp.UIAxes.YGrid = 'on';
            comp.UIAxes.FontSize = 10;
            comp.UIAxes.Position = [208 432 495 308];

            % Create UIAxes_2
            comp.UIAxes_2 = uiaxes(comp);
            xlabel(comp.UIAxes_2, 'Frequency (Hz)')
            ylabel(comp.UIAxes_2, 'Magnitude')
            zlabel(comp.UIAxes_2, 'Z')
            comp.UIAxes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            comp.UIAxes_2.XGrid = 'on';
            comp.UIAxes_2.YGrid = 'on';
            comp.UIAxes_2.FontSize = 10;
            comp.UIAxes_2.Position = [208 126 495 308];

            % Create UIAxes_3
            comp.UIAxes_3 = uiaxes(comp);
            xlabel(comp.UIAxes_3, 'Frequency (Hz)')
            zlabel(comp.UIAxes_3, 'Z')
            comp.UIAxes_3.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            comp.UIAxes_3.XGrid = 'on';
            comp.UIAxes_3.YGrid = 'on';
            comp.UIAxes_3.FontSize = 10;
            comp.UIAxes_3.Position = [701 126 495 308];

            % Create UIAxes_4
            comp.UIAxes_4 = uiaxes(comp);
            xlabel(comp.UIAxes_4, 'Time (s)')
            zlabel(comp.UIAxes_4, 'Z')
            comp.UIAxes_4.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            comp.UIAxes_4.XGrid = 'on';
            comp.UIAxes_4.YGrid = 'on';
            comp.UIAxes_4.FontSize = 10;
            comp.UIAxes_4.Position = [701 432 495 308];

            % Create APPLYButton
            comp.APPLYButton = uibutton(comp, 'push');
            comp.APPLYButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @APPLYButtonPushed, true);
            comp.APPLYButton.Position = [1029 66 100 23];
            comp.APPLYButton.Text = 'APPLY';

            % Create FsEditFieldLabel
            comp.FsEditFieldLabel = uilabel(comp);
            comp.FsEditFieldLabel.HorizontalAlignment = 'right';
            comp.FsEditFieldLabel.Position = [36 396 25 22];
            comp.FsEditFieldLabel.Text = 'Fs';

            % Create FsEditField
            comp.FsEditField = uieditfield(comp, 'numeric');
            comp.FsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FsEditFieldValueChanged, true);
            comp.FsEditField.Position = [76 396 100 22];

            % Create Fpass1EditFieldLabel
            comp.Fpass1EditFieldLabel = uilabel(comp);
            comp.Fpass1EditFieldLabel.HorizontalAlignment = 'right';
            comp.Fpass1EditFieldLabel.Position = [16 363 45 22];
            comp.Fpass1EditFieldLabel.Text = 'Fpass1';

            % Create Fpass1EditField
            comp.Fpass1EditField = uieditfield(comp, 'numeric');
            comp.Fpass1EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Fpass1EditFieldValueChanged, true);
            comp.Fpass1EditField.Position = [76 363 100 22];

            % Create RECButton
            comp.RECButton = uibutton(comp, 'push');
            comp.RECButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RECButtonPushed, true);
            comp.RECButton.Position = [16 720 100 23];
            comp.RECButton.Text = 'REC';

            % Create PLAYButton
            comp.PLAYButton = uibutton(comp, 'push');
            comp.PLAYButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PLAYButtonPushed, true);
            comp.PLAYButton.Position = [16 680 100 23];
            comp.PLAYButton.Text = 'PLAY';

            % Create DoneLampLabel
            comp.DoneLampLabel = uilabel(comp);
            comp.DoneLampLabel.HorizontalAlignment = 'right';
            comp.DoneLampLabel.Position = [126 720 34 22];
            comp.DoneLampLabel.Text = 'Done';

            % Create DoneLamp
            comp.DoneLamp = uilamp(comp);
            comp.DoneLamp.Position = [175 720 20 20];

            % Create Fpass2EditFieldLabel
            comp.Fpass2EditFieldLabel = uilabel(comp);
            comp.Fpass2EditFieldLabel.HorizontalAlignment = 'right';
            comp.Fpass2EditFieldLabel.Position = [17 325 45 22];
            comp.Fpass2EditFieldLabel.Text = 'Fpass2';

            % Create Fpass2EditField
            comp.Fpass2EditField = uieditfield(comp, 'numeric');
            comp.Fpass2EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Fpass2EditFieldValueChanged, true);
            comp.Fpass2EditField.Position = [77 325 100 22];

            % Create DoneLamp_2Label
            comp.DoneLamp_2Label = uilabel(comp);
            comp.DoneLamp_2Label.HorizontalAlignment = 'right';
            comp.DoneLamp_2Label.Position = [126 679 34 22];
            comp.DoneLamp_2Label.Text = 'Done';

            % Create DoneLamp_2
            comp.DoneLamp_2 = uilamp(comp);
            comp.DoneLamp_2.Position = [175 679 20 20];

            % Create DoneLamp_3Label
            comp.DoneLamp_3Label = uilabel(comp);
            comp.DoneLamp_3Label.HorizontalAlignment = 'right';
            comp.DoneLamp_3Label.Position = [948 67 34 22];
            comp.DoneLamp_3Label.Text = 'Done';

            % Create DoneLamp_3
            comp.DoneLamp_3 = uilamp(comp);
            comp.DoneLamp_3.Position = [997 67 20 20];

            % Create PLAYButton_2
            comp.PLAYButton_2 = uibutton(comp, 'push');
            comp.PLAYButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PLAYButton_2Pushed, true);
            comp.PLAYButton_2.Position = [1029 30 100 23];
            comp.PLAYButton_2.Text = 'PLAY';

            % Create DoneLamp_4Label
            comp.DoneLamp_4Label = uilabel(comp);
            comp.DoneLamp_4Label.HorizontalAlignment = 'right';
            comp.DoneLamp_4Label.Position = [948 31 34 22];
            comp.DoneLamp_4Label.Text = 'Done';

            % Create DoneLamp_4
            comp.DoneLamp_4 = uilamp(comp);
            comp.DoneLamp_4.Position = [997 31 20 20];
            
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end